classdef create_game < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MonopolyInMatlabUIFigure  matlab.ui.Figure
        ErrorLabel                matlab.ui.control.Label
        BackButton_2              matlab.ui.control.Button
        CreateGameButton          matlab.ui.control.Button
        UsernameEditField         matlab.ui.control.EditField
        RoomNameEditFieldLabel_2  matlab.ui.control.Label
        RoomNameEditField         matlab.ui.control.EditField
        RoomNameEditFieldLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        dev_mode = false
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dev_mode)
            app.dev_mode = dev_mode;
        end

        % Button pushed function: BackButton_2
        function BackButton_2Pushed(app, event)
            dev_mode_temp = app.dev_mode;            
            delete(app);
            main_menu(dev_mode_temp);
        end

        % Button pushed function: CreateGameButton
        function CreateGameButtonPushed(app, event)
            import displayError.*;
            import webRequest.*;
            import StompClient.*;
            
            if(isempty(app.RoomNameEditField.Value) || isempty(app.UsernameEditField.Value))
                displayError(app.ErrorLabel, "You need to provide both a username and a room name");
                return;
            end

            data = struct("roomName", app.RoomNameEditField.Value);
            playerData = struct("playerName", app.UsernameEditField.Value);
            if(app.dev_mode == true)
                response = webRequest("http://localhost:8000/menu/createroom", data); % we are getting the room details back from the server
            else
                response = webRequest("https://monopolyinmatlabserver-production.up.railway.app/menu/createroom", data); % we are getting the room details back from the server
            end
            if (~isempty(response.Body.Data))
               client = StompClient.getInstance();
               client.joinRoom(response.Body.Data.roomId, app.UsernameEditField.Value); % this subscribes to the room and sends a joined room broadcast to that room in the websocket server
               if(app.dev_mode == true)
                    fprintf("http://localhost:8000/menu/rooms/%s/join", response.Body.Data.roomId);
                    playerJoinResponse = webRequest(sprintf("http://localhost:8000/menu/rooms/%s/join", response.Body.Data.roomId), playerData); % this actually tells the server to put this player in the room
               else
                    fprintf("https://monopolyinmatlabserver-production.up.railway.app/menu/rooms/%s/join", response.Body.Data.roomId);
                    playerJoinResponse = webRequest(sprintf("https://monopolyinmatlabserver-production.up.railway.app/menu/rooms/%s/join", response.Body.Data.roomId), playerData); % this actually tells the server to put this player in the room
               end
               dev_mode_temp = app.dev_mode;               
               delete(app);
               game(response.Body.Data.roomId, true, playerJoinResponse.Body.Data.playerId, dev_mode_temp); % we are passing the roomId to be consumed by the gameInstance function and that the user is host
            else
                displayError(app.ErrorLabel, "Error occured");
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MonopolyInMatlabUIFigure = uifigure('Visible', 'off');
            app.MonopolyInMatlabUIFigure.Name = 'Monopoly In Matlab';
            app.RoomNameEditFieldLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.RoomNameEditFieldLabel.Text = 'Room Name';
            app.RoomNameEditField = uieditfield(app.MonopolyInMatlabUIFigure, 'text');
            app.RoomNameEditFieldLabel_2 = uilabel(app.MonopolyInMatlabUIFigure);
            app.RoomNameEditFieldLabel_2.Text = 'Your username';
            app.UsernameEditField = uieditfield(app.MonopolyInMatlabUIFigure, 'text');
            app.CreateGameButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.CreateGameButton.ButtonPushedFcn = createCallbackFcn(app, @CreateGameButtonPushed, true);
            app.CreateGameButton.Text = 'Create Game';
            app.BackButton_2 = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButton_2Pushed, true);
            app.BackButton_2.Text = 'Back';
            app.ErrorLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.ErrorLabel.Visible = 'off';
            app.ErrorLabel.Text = 'Error';
            % Show the figure after all components are created
            app.MonopolyInMatlabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = create_game(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MonopolyInMatlabUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MonopolyInMatlabUIFigure)
        end
    end
end