classdef game < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MonopolyInMatlabUIFigure        matlab.ui.Figure
        liquidation_panel               matlab.ui.container.Panel
        SellMortgageSelectedButton      matlab.ui.control.Button
        Have0Label                      matlab.ui.control.Label
        Need0Label                      matlab.ui.control.Label
        SellMortgageTable               matlab.ui.control.Table
        card_drawn_panel                matlab.ui.container.Panel
        YouGotLabel                     matlab.ui.control.Label
        OkButton                        matlab.ui.control.Button
        CardDrawnImage                  matlab.ui.control.Image
        ChooseAPropertyPanel            matlab.ui.container.Panel
        ListBox                         matlab.ui.control.ListBox
        PaybailButton                   matlab.ui.control.Button
        dog_19                          matlab.ui.control.Image
        battleship_19                   matlab.ui.control.Image
        hat_19                          matlab.ui.control.Image
        car_19                          matlab.ui.control.Image
        dog_18                          matlab.ui.control.Image
        battleship_18                   matlab.ui.control.Image
        hat_18                          matlab.ui.control.Image
        car_18                          matlab.ui.control.Image
        dog_17                          matlab.ui.control.Image
        battleship_17                   matlab.ui.control.Image
        hat_17                          matlab.ui.control.Image
        car_17                          matlab.ui.control.Image
        dog_16                          matlab.ui.control.Image
        battleship_16                   matlab.ui.control.Image
        hat_16                          matlab.ui.control.Image
        car_16                          matlab.ui.control.Image
        dog_15                          matlab.ui.control.Image
        battleship_15                   matlab.ui.control.Image
        hat_15                          matlab.ui.control.Image
        car_15                          matlab.ui.control.Image
        dog_14                          matlab.ui.control.Image
        battleship_14                   matlab.ui.control.Image
        hat_14                          matlab.ui.control.Image
        car_14                          matlab.ui.control.Image
        dog_13                          matlab.ui.control.Image
        battleship_13                   matlab.ui.control.Image
        hat_13                          matlab.ui.control.Image
        car_13                          matlab.ui.control.Image
        dog_12                          matlab.ui.control.Image
        battleship_12                   matlab.ui.control.Image
        hat_12                          matlab.ui.control.Image
        car_12                          matlab.ui.control.Image
        dog_11                          matlab.ui.control.Image
        battleship_11                   matlab.ui.control.Image
        hat_11                          matlab.ui.control.Image
        car_11                          matlab.ui.control.Image
        dog_39                          matlab.ui.control.Image
        battleship_39                   matlab.ui.control.Image
        hat_39                          matlab.ui.control.Image
        car_39                          matlab.ui.control.Image
        dog_38                          matlab.ui.control.Image
        battleship_38                   matlab.ui.control.Image
        hat_38                          matlab.ui.control.Image
        car_38                          matlab.ui.control.Image
        dog_37                          matlab.ui.control.Image
        battleship_37                   matlab.ui.control.Image
        hat_37                          matlab.ui.control.Image
        car_37                          matlab.ui.control.Image
        dog_36                          matlab.ui.control.Image
        battleship_36                   matlab.ui.control.Image
        hat_36                          matlab.ui.control.Image
        car_36                          matlab.ui.control.Image
        dog_35                          matlab.ui.control.Image
        battleship_35                   matlab.ui.control.Image
        hat_35                          matlab.ui.control.Image
        car_35                          matlab.ui.control.Image
        dog_34                          matlab.ui.control.Image
        battleship_34                   matlab.ui.control.Image
        hat_34                          matlab.ui.control.Image
        car_34                          matlab.ui.control.Image
        dog_33                          matlab.ui.control.Image
        battleship_33                   matlab.ui.control.Image
        hat_33                          matlab.ui.control.Image
        car_33                          matlab.ui.control.Image
        dog_32                          matlab.ui.control.Image
        battleship_32                   matlab.ui.control.Image
        hat_32                          matlab.ui.control.Image
        car_32                          matlab.ui.control.Image
        dog_31                          matlab.ui.control.Image
        battleship_31                   matlab.ui.control.Image
        hat_31                          matlab.ui.control.Image
        car_31                          matlab.ui.control.Image
        dog_30                          matlab.ui.control.Image
        battleship_30                   matlab.ui.control.Image
        hat_30                          matlab.ui.control.Image
        car_30                          matlab.ui.control.Image
        dog_22                          matlab.ui.control.Image
        battleship_22                   matlab.ui.control.Image
        hat_22                          matlab.ui.control.Image
        car_22                          matlab.ui.control.Image
        dog_29                          matlab.ui.control.Image
        battleship_29                   matlab.ui.control.Image
        hat_29                          matlab.ui.control.Image
        car_29                          matlab.ui.control.Image
        dog_28                          matlab.ui.control.Image
        battleship_28                   matlab.ui.control.Image
        hat_28                          matlab.ui.control.Image
        car_28                          matlab.ui.control.Image
        dog_27                          matlab.ui.control.Image
        battleship_27                   matlab.ui.control.Image
        hat_27                          matlab.ui.control.Image
        car_27                          matlab.ui.control.Image
        dog_26                          matlab.ui.control.Image
        battleship_26                   matlab.ui.control.Image
        hat_26                          matlab.ui.control.Image
        car_26                          matlab.ui.control.Image
        dog_25                          matlab.ui.control.Image
        battleship_25                   matlab.ui.control.Image
        hat_25                          matlab.ui.control.Image
        car_25                          matlab.ui.control.Image
        dog_24                          matlab.ui.control.Image
        battleship_24                   matlab.ui.control.Image
        hat_24                          matlab.ui.control.Image
        car_24                          matlab.ui.control.Image
        dog_23                          matlab.ui.control.Image
        battleship_23                   matlab.ui.control.Image
        hat_23                          matlab.ui.control.Image
        car_23                          matlab.ui.control.Image
        dog_21                          matlab.ui.control.Image
        battleship_21                   matlab.ui.control.Image
        hat_21                          matlab.ui.control.Image
        car_21                          matlab.ui.control.Image
        dog_20                          matlab.ui.control.Image
        battleship_20                   matlab.ui.control.Image
        hat_20                          matlab.ui.control.Image
        car_20                          matlab.ui.control.Image
        hat_10                          matlab.ui.control.Image
        car_10                          matlab.ui.control.Image
        dog_jail_10                     matlab.ui.control.Image
        battleship_jail_10              matlab.ui.control.Image
        dog_10                          matlab.ui.control.Image
        battleship_10                   matlab.ui.control.Image
        hat_jail_10                     matlab.ui.control.Image
        car_jail_10                     matlab.ui.control.Image
        dog_9                           matlab.ui.control.Image
        battleship_9                    matlab.ui.control.Image
        hat_9                           matlab.ui.control.Image
        car_9                           matlab.ui.control.Image
        dog_8                           matlab.ui.control.Image
        battleship_8                    matlab.ui.control.Image
        hat_8                           matlab.ui.control.Image
        car_8                           matlab.ui.control.Image
        dog_7                           matlab.ui.control.Image
        battleship_7                    matlab.ui.control.Image
        hat_7                           matlab.ui.control.Image
        car_7                           matlab.ui.control.Image
        dog_6                           matlab.ui.control.Image
        battleship_6                    matlab.ui.control.Image
        hat_6                           matlab.ui.control.Image
        car_6                           matlab.ui.control.Image
        dog_5                           matlab.ui.control.Image
        battleship_5                    matlab.ui.control.Image
        hat_5                           matlab.ui.control.Image
        car_5                           matlab.ui.control.Image
        dog_4                           matlab.ui.control.Image
        battleship_4                    matlab.ui.control.Image
        hat_4                           matlab.ui.control.Image
        car_4                           matlab.ui.control.Image
        dog_3                           matlab.ui.control.Image
        battleship_3                    matlab.ui.control.Image
        hat_3                           matlab.ui.control.Image
        car_3                           matlab.ui.control.Image
        dog_2                           matlab.ui.control.Image
        battleship_2                    matlab.ui.control.Image
        hat_2                           matlab.ui.control.Image
        car_2                           matlab.ui.control.Image
        dog_1                           matlab.ui.control.Image
        battleship_1                    matlab.ui.control.Image
        hat_1                           matlab.ui.control.Image
        car_1                           matlab.ui.control.Image
        dog_0                           matlab.ui.control.Image
        battleship_0                    matlab.ui.control.Image
        hat_0                           matlab.ui.control.Image
        car_0                           matlab.ui.control.Image
        propertiesLabel                 matlab.ui.control.Label
        Player4Label                    matlab.ui.control.Label
        Player3Label                    matlab.ui.control.Label
        EndTurnButton                   matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        YouvegonebankruptLabel          matlab.ui.control.Label
        UseGetoutofjailfreecardButton   matlab.ui.control.Button
        OptionsLabel                    matlab.ui.control.Label
        Player2Label                    matlab.ui.control.Label
        Player1Label                    matlab.ui.control.Label
        CurrentSpotOptionsLabel         matlab.ui.control.Label
        Image3_4                        matlab.ui.control.Image
        Image3_3                        matlab.ui.control.Image
        Image3_2                        matlab.ui.control.Image
        Image3                          matlab.ui.control.Image
        BuyahouseButton                 matlab.ui.control.Button
        BuyahotelButton                 matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        BuyPropertyHereButton           matlab.ui.control.Button
        SellAPropertyButton             matlab.ui.control.Button
        LeavegameButton                 matlab.ui.control.Button
        CurrentTurnGamenotstartedLabel  matlab.ui.control.Label
        YourPropertiesLabel             matlab.ui.control.Label
        YourFunds100Label               matlab.ui.control.Label
        PlayersConnectedLabel           matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        TextArea                        matlab.ui.control.TextArea
        ChatEditField                   matlab.ui.control.EditField
    end

    
    properties (Access = private)
        GameInstance % Description
        dev_mode
    end

   properties
        DisabledRows = [];
        sellValue = 0;
        debtValue = 0;
    end
    
    methods (Access = public)
        
        function disableRows(app, rowsToDisable)
            app.DisabledRows = unique(rowsToDisable);

            % Update background colors
            numRows = size(app.SellMortgageTable.Data, 1);
            app.SellMortgageTable.BackgroundColor = repmat([0.07,0.07,0.07], numRows, 1); % set all rows to default hue
            
            % Gray out disabled rows
            if ~isempty(app.DisabledRows)
                app.SellMortgageTable.BackgroundColor(app.DisabledRows, :) = repmat([0.9 0.9 0.9], length(app.DisabledRows), 1);
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, roomId, userIsHost, localPlayerId, dev_mode)
            import Game.*;
            import StompClient.*;

            app.dev_mode = dev_mode;
            
            try
                app.GameInstance = Game.gameInstance(app, roomId, userIsHost, localPlayerId, app.dev_mode);
                app.GameInstance.setStompClient(StompClient.getInstance());
            catch exception
                
            end
        end

        % Button pushed function: BuyahouseButton
        function BuyahouseButtonPushed(app, event)
            if(app.Panel.Visible == "on")
                app.Panel.Visible = "off";
            else
                app.Panel.Visible = "on";
            end
        end

        % Button pushed function: LeavegameButton
        function LeavegameButtonPushed(app, event)
            dev_mode_temp = app.dev_mode;
            delete(app);
            main_menu(dev_mode_temp);
        end

        % Window key press function: MonopolyInMatlabUIFigure
        function MonopolyInMatlabUIFigureWindowKeyPress(app, event)
            if(strcmp(event.Key, "return"))
                focusedComponent = app.MonopolyInMatlabUIFigure.CurrentObject;

                if((focusedComponent == app.ChatEditField) && ~isempty(app.ChatEditField.Value))
                    app.GameInstance.sendChatMessage(app.ChatEditField.Value);
                    app.ChatEditField.Value = "";
                end
            end
            
        end

        % Button pushed function: EndTurnButton
        function EndTurnButtonPushed(app, event)
            app.EndTurnButton.Enable = "off";
            switch(app.EndTurnButton.Text)
                case "Start Game"
                    if(app.GameInstance.weAreHost == true)
                        app.GameInstance.requestStartGame();
                    end
                case "Roll"
                    if(app.GameInstance.getMyPlayer().inJail == true)
                        app.GameInstance.requestJailAction("ROLL");
                        if(app.GameInstance.getMyPlayer().inJail)
                            app.GameInstance.requestEndTurn();
                        else
                            app.EndTurnButton.Enable = "on";
                        end
                    else
                        app.GameInstance.requestRollDice();
                    end
                case "End turn"
                    % TODO: if ending turn on unowned property do
                    % app.GameInstance.requestDeclineProperty(); this
                    % should start an auction
                    app.GameInstance.requestEndTurn();
                case "Draw Card"
                    playerPosition = app.GameInstance.getMyPlayer().position;
                    if(playerPosition == 7 || playerPosition == 36 || playerPosition == 22)
                        app.GameInstance.requestDrawCard("CHANCE");
                    elseif(playerPosition == 2 || playerPosition == 17 || playerPosition == 33)
                        app.GameInstance.requestDrawCard("COMMUNITY");
                    end
            end
        end

        % Button pushed function: BuyPropertyHereButton
        function BuyPropertyHereButtonPushed(app, event)
            app.BuyPropertyHereButton.Enable = "off";
            app.GameInstance.requestBuyProperty(app.GameInstance.getMyPlayer().position);
        end

        % Button pushed function: PaybailButton
        function PaybailButtonPushed(app, event)
            app.PaybailButton.Enable = "off";
            app.GameInstance.requestJailAction("PAY");
        end

        % Button pushed function: UseGetoutofjailfreecardButton
        function UseGetoutofjailfreecardButtonPushed(app, event)
            app.PaybailButton.Enable = "off";
            app.GameInstance.requestJailAction("CARD");
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            app.card_drawn_panel.Visible = "off";
        end

        % Cell edit callback: SellMortgageTable
        function SellMortgageTableCellEdit(app, event)
            indices = event.Indices; % [row, column]
            newData = event.NewData; % new data of row and column pressed on
            rowData = app.SellMortgageTable.Data(indices(1), :);
            fprintf("clicked")

            checkbox = rowData{1};    % Checkbox value
            name = rowData{2};        % Name
            mortgageValue = rowData{3};  % Mortgage value
            parentRow = rowData{4};   % Parent row number

            % if row checkmarked is disabled revert back to previous data
            if ismember(event.Indices(1), app.DisabledRows)
                app.SellMortgageTable.Data(event.Indices(1), event.Indices(2)) = event.PreviousData;
                return;
            end

            % check to see if it is a house and if it is check to see if
            % all houses built on that property are checked and if it is
            % enable the row of that property
            if(~isempty(parentRow)) % if true, this means we have a hotel or a house
                % parse through app.SellMortgageTable.Data and get all rows
                % that have a parent row id of the one we just pressed, and if
                % they are all true set the parent to enable row
                parentRowData = app.SellMortgageTable.Data(parentRow, :);
                parentRowProperty = app.GameInstance.getPropertyByName(parentRowData{2});

               
                if(checkbox == true) % try to remove parent row from disabled
                    % add mortgage value to sellValue 
                    if(parentRowProperty.hasHotel == true)
                        app.sellValue = app.sellValue + parentRowProperty.hotelCost / 2;
                    else
                        app.sellValue = app.sellValue + (parentRowProperty.houseCost / 2);
                    end

                    enablePropertyRow = true;
                    for i = 1 : size(app.SellMortgageTable.Data, 1)
                        loopRowData = app.SellMortgageTable.Data(i, :);
                        if((contains(loopRowData{2}, "house") || contains(loopRowData{2}, "hotel")) && loopRowData{4} == parentRow && loopRowData{1} == false)
                            % if there is a single one that says false, then
                            % dont enable
                            enablePropertyRow = false;
                            break;
                        end
                    end

                    if(enablePropertyRow == true)
                        idxOfDisabledProperty = find(app.DisabledRows == parentRow);
                        app.DisabledRows(idxOfDisabledProperty) = [];
                        app.disableRows(app.DisabledRows);
                    end
                else % add to disabled
                    % remove from sellValue by mortgage value
                    app.disableRows([app.DisabledRows, parentRow]);
                    if(parentRowProperty.hasHotel == true)
                        app.sellValue = app.sellValue - parentRowProperty.hotelCost / 2;
                    else
                        app.sellValue = app.sellValue - (parentRowProperty.houseCost / 2);
                    end
                end
                
            else
                if(checkbox == true)
                    property = app.GameInstance.getPropertyByName(rowData{2});
                    fprintf(rowData{2});
                    fprintf(property);
                    app.sellValue = app.sellValue + property.mortgageValue;
                else
                    property = app.GameInstance.getPropertyByName(rowData{2});
                    app.sellValue = app.sellValue - property.mortgageValue;
                end
            end

            % if sellValue is greater than or equal to debtValue, set
            % debtValue font to green
            if(app.sellValue >= app.debtValue)
                app.Need0Label.FontColor = "green";
            else
                app.Need0Label.FontColor = "red";
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MonopolyInMatlabUIFigure = uifigure('Visible', 'off');
            app.MonopolyInMatlabUIFigure.Name = 'Monopoly In Matlab';
            app.MonopolyInMatlabUIFigure.WindowKeyPressFcn = createCallbackFcn(app, @MonopolyInMatlabUIFigureWindowKeyPress, true);
            app.ChatEditField = uieditfield(app.MonopolyInMatlabUIFigure, 'text');
            app.ChatEditField.Placeholder = 'Enter message';
            app.TextArea = uitextarea(app.MonopolyInMatlabUIFigure);
            app.TextArea.Editable = 'off';
            app.Image = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image.ImageSource = 'monopoly_board.jpg';
            app.PlayersConnectedLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.PlayersConnectedLabel.Text = 'Players Connected(N/A): ';
            app.YourFunds100Label = uilabel(app.MonopolyInMatlabUIFigure);
            app.YourFunds100Label.Text = 'Your Funds: $100';
            app.YourPropertiesLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.YourPropertiesLabel.Text = 'Your Properties:';
            app.CurrentTurnGamenotstartedLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.CurrentTurnGamenotstartedLabel.Text = 'Current Turn: Game not started';
            app.LeavegameButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.LeavegameButton.ButtonPushedFcn = createCallbackFcn(app, @LeavegameButtonPushed, true);
            app.LeavegameButton.Text = 'Leave game';
            app.SellAPropertyButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.SellAPropertyButton.Enable = 'off';
            app.SellAPropertyButton.Text = 'Sell A Property';
            app.BuyPropertyHereButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.BuyPropertyHereButton.ButtonPushedFcn = createCallbackFcn(app, @BuyPropertyHereButtonPushed, true);
            app.BuyPropertyHereButton.Enable = 'off';
            app.BuyPropertyHereButton.Text = 'Buy Property Here';
            app.Image2 = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image2.ImageSource = 'hotel.png';
            app.BuyahotelButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.BuyahotelButton.Enable = 'off';
            app.BuyahotelButton.Text = 'Buy a hotel';
            app.BuyahouseButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.BuyahouseButton.ButtonPushedFcn = createCallbackFcn(app, @BuyahouseButtonPushed, true);
            app.BuyahouseButton.Enable = 'off';
            app.BuyahouseButton.Text = 'Buy a house';
            app.Image3 = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image3.ImageSource = 'house.png';
            app.Image3_2 = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image3_2.ImageSource = 'house.png';
            app.Image3_3 = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image3_3.ImageSource = 'house.png';
            app.Image3_4 = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image3_4.ImageSource = 'house.png';
            app.CurrentSpotOptionsLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.CurrentSpotOptionsLabel.Enable = 'off';
            app.CurrentSpotOptionsLabel.Text = 'Current Spot Options';
            app.Player1Label = uilabel(app.MonopolyInMatlabUIFigure);
            app.Player1Label.Text = '';
            app.Player2Label = uilabel(app.MonopolyInMatlabUIFigure);
            app.Player2Label.Text = '';
            app.OptionsLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.OptionsLabel.Enable = 'off';
            app.OptionsLabel.Text = 'Options';
            app.UseGetoutofjailfreecardButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.UseGetoutofjailfreecardButton.ButtonPushedFcn = createCallbackFcn(app, @UseGetoutofjailfreecardButtonPushed, true);
            app.UseGetoutofjailfreecardButton.Enable = 'off';
            app.UseGetoutofjailfreecardButton.Text = 'Use Get out of jail free card';
            app.Panel = uipanel(app.MonopolyInMatlabUIFigure);
            app.Panel.Visible = 'off';
            app.YouvegonebankruptLabel = uilabel(app.Panel);
            app.YouvegonebankruptLabel.Text = 'Youve gone bankrupt!';
            app.EndTurnButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.EndTurnButton.ButtonPushedFcn = createCallbackFcn(app, @EndTurnButtonPushed, true);
            app.EndTurnButton.Text = 'End Turn';
            app.Player3Label = uilabel(app.MonopolyInMatlabUIFigure);
            app.Player3Label.Text = '';
            app.Player4Label = uilabel(app.MonopolyInMatlabUIFigure);
            app.Player4Label.Text = '';
            app.propertiesLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.propertiesLabel.Text = '';
            app.car_0 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_0.Visible = 'off';
            app.car_0.ImageSource = fullfile(pathToMLAPP, 'car_left.png');
            app.hat_0 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_0.Visible = 'off';
            app.hat_0.ImageSource = fullfile(pathToMLAPP, 'hat_left.png');
            app.battleship_0 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_0.Visible = 'off';
            app.battleship_0.ImageSource = fullfile(pathToMLAPP, 'battleship_left.png');
            app.dog_0 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_0.Visible = 'off';
            app.dog_0.ImageSource = fullfile(pathToMLAPP, 'dog_left.png');
            app.car_1 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_1.Visible = 'off';
            app.car_1.ImageSource = fullfile(pathToMLAPP, 'car_left.png');
            app.hat_1 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_1.Visible = 'off';
            app.hat_1.ImageSource = fullfile(pathToMLAPP, 'hat_left.png');
            app.battleship_1 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_1.Visible = 'off';
            app.battleship_1.ImageSource = fullfile(pathToMLAPP, 'battleship_left.png');
            app.dog_1 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_1.Visible = 'off';
            app.dog_1.ImageSource = fullfile(pathToMLAPP, 'dog_left.png');
            app.car_2 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_2.Visible = 'off';
            app.car_2.ImageSource = fullfile(pathToMLAPP, 'car_left.png');
            app.hat_2 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_2.Visible = 'off';
            app.hat_2.ImageSource = fullfile(pathToMLAPP, 'hat_left.png');
            app.battleship_2 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_2.Visible = 'off';
            app.battleship_2.ImageSource = fullfile(pathToMLAPP, 'battleship_left.png');
            app.dog_2 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_2.Visible = 'off';
            app.dog_2.ImageSource = fullfile(pathToMLAPP, 'dog_left.png');
            app.car_3 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_3.Visible = 'off';
            app.car_3.ImageSource = fullfile(pathToMLAPP, 'car_left.png');
            app.hat_3 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_3.Visible = 'off';
            app.hat_3.ImageSource = fullfile(pathToMLAPP, 'hat_left.png');
            app.battleship_3 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_3.Visible = 'off';
            app.battleship_3.ImageSource = fullfile(pathToMLAPP, 'battleship_left.png');
            app.dog_3 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_3.Visible = 'off';
            app.dog_3.ImageSource = fullfile(pathToMLAPP, 'dog_left.png');
            app.car_4 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_4.Visible = 'off';
            app.car_4.ImageSource = fullfile(pathToMLAPP, 'car_left.png');
            app.hat_4 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_4.Visible = 'off';
            app.hat_4.ImageSource = fullfile(pathToMLAPP, 'hat_left.png');
            app.battleship_4 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_4.Visible = 'off';
            app.battleship_4.ImageSource = fullfile(pathToMLAPP, 'battleship_left.png');
            app.dog_4 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_4.Visible = 'off';
            app.dog_4.ImageSource = fullfile(pathToMLAPP, 'dog_left.png');
            app.car_5 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_5.Visible = 'off';
            app.car_5.ImageSource = fullfile(pathToMLAPP, 'car_left.png');
            app.hat_5 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_5.Visible = 'off';
            app.hat_5.ImageSource = fullfile(pathToMLAPP, 'hat_left.png');
            app.battleship_5 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_5.Visible = 'off';
            app.battleship_5.ImageSource = fullfile(pathToMLAPP, 'battleship_left.png');
            app.dog_5 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_5.Visible = 'off';
            app.dog_5.ImageSource = fullfile(pathToMLAPP, 'dog_left.png');
            app.car_6 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_6.Visible = 'off';
            app.car_6.ImageSource = fullfile(pathToMLAPP, 'car_left.png');
            app.hat_6 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_6.Visible = 'off';
            app.hat_6.ImageSource = fullfile(pathToMLAPP, 'hat_left.png');
            app.battleship_6 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_6.Visible = 'off';
            app.battleship_6.ImageSource = fullfile(pathToMLAPP, 'battleship_left.png');
            app.dog_6 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_6.Visible = 'off';
            app.dog_6.ImageSource = fullfile(pathToMLAPP, 'dog_left.png');
            app.car_7 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_7.Visible = 'off';
            app.car_7.ImageSource = fullfile(pathToMLAPP, 'car_left.png');
            app.hat_7 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_7.Visible = 'off';
            app.hat_7.ImageSource = fullfile(pathToMLAPP, 'hat_left.png');
            app.battleship_7 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_7.Visible = 'off';
            app.battleship_7.ImageSource = fullfile(pathToMLAPP, 'battleship_left.png');
            app.dog_7 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_7.Visible = 'off';
            app.dog_7.ImageSource = fullfile(pathToMLAPP, 'dog_left.png');
            app.car_8 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_8.Visible = 'off';
            app.car_8.ImageSource = fullfile(pathToMLAPP, 'car_left.png');
            app.hat_8 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_8.Visible = 'off';
            app.hat_8.ImageSource = fullfile(pathToMLAPP, 'hat_left.png');
            app.battleship_8 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_8.Visible = 'off';
            app.battleship_8.ImageSource = fullfile(pathToMLAPP, 'battleship_left.png');
            app.dog_8 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_8.Visible = 'off';
            app.dog_8.ImageSource = fullfile(pathToMLAPP, 'dog_left.png');
            app.car_9 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_9.Visible = 'off';
            app.car_9.ImageSource = fullfile(pathToMLAPP, 'car_left.png');
            app.hat_9 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_9.Visible = 'off';
            app.hat_9.ImageSource = fullfile(pathToMLAPP, 'hat_left.png');
            app.battleship_9 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_9.Visible = 'off';
            app.battleship_9.ImageSource = fullfile(pathToMLAPP, 'battleship_left.png');
            app.dog_9 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_9.Visible = 'off';
            app.dog_9.ImageSource = fullfile(pathToMLAPP, 'dog_left.png');
            app.car_jail_10 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_jail_10.Visible = 'off';
            app.car_jail_10.ImageSource = fullfile(pathToMLAPP, 'car_left.png');
            app.hat_jail_10 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_jail_10.Visible = 'off';
            app.hat_jail_10.ImageSource = fullfile(pathToMLAPP, 'hat_left.png');
            app.battleship_10 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_10.Visible = 'off';
            app.battleship_10.ImageSource = fullfile(pathToMLAPP, 'battleship_left.png');
            app.dog_10 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_10.Visible = 'off';
            app.dog_10.ImageSource = fullfile(pathToMLAPP, 'dog_left.png');
            app.battleship_jail_10 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_jail_10.Visible = 'off';
            app.battleship_jail_10.ImageSource = fullfile(pathToMLAPP, 'battleship_left.png');
            app.dog_jail_10 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_jail_10.Visible = 'off';
            app.dog_jail_10.ImageSource = fullfile(pathToMLAPP, 'dog_left.png');
            app.car_10 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_10.Visible = 'off';
            app.car_10.ImageSource = fullfile(pathToMLAPP, 'car_up.png');
            app.hat_10 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_10.Visible = 'off';
            app.hat_10.ImageSource = fullfile(pathToMLAPP, 'hat_up.png');
            app.car_20 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_20.Visible = 'off';
            app.car_20.ImageSource = fullfile(pathToMLAPP, 'car_right.png');
            app.hat_20 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_20.Visible = 'off';
            app.hat_20.ImageSource = fullfile(pathToMLAPP, 'hat_right.png');
            app.battleship_20 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_20.Visible = 'off';
            app.battleship_20.ImageSource = fullfile(pathToMLAPP, 'battleship_right.png');
            app.dog_20 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_20.Visible = 'off';
            app.dog_20.ImageSource = fullfile(pathToMLAPP, 'dog_right.png');
            app.car_21 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_21.Visible = 'off';
            app.car_21.ImageSource = fullfile(pathToMLAPP, 'car_right.png');
            app.hat_21 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_21.Visible = 'off';
            app.hat_21.ImageSource = fullfile(pathToMLAPP, 'hat_right.png');
            app.battleship_21 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_21.Visible = 'off';
            app.battleship_21.ImageSource = fullfile(pathToMLAPP, 'battleship_right.png');
            app.dog_21 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_21.Visible = 'off';
            app.dog_21.ImageSource = fullfile(pathToMLAPP, 'dog_right.png');
            app.car_23 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_23.Visible = 'off';
            app.car_23.ImageSource = fullfile(pathToMLAPP, 'car_right.png');
            app.hat_23 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_23.Visible = 'off';
            app.hat_23.ImageSource = fullfile(pathToMLAPP, 'hat_right.png');
            app.battleship_23 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_23.Visible = 'off';
            app.battleship_23.ImageSource = fullfile(pathToMLAPP, 'battleship_right.png');
            app.dog_23 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_23.Visible = 'off';
            app.dog_23.ImageSource = fullfile(pathToMLAPP, 'dog_right.png');
            app.car_24 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_24.Visible = 'off';
            app.car_24.ImageSource = fullfile(pathToMLAPP, 'car_right.png');
            app.hat_24 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_24.Visible = 'off';
            app.hat_24.ImageSource = fullfile(pathToMLAPP, 'hat_right.png');
            app.battleship_24 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_24.Visible = 'off';
            app.battleship_24.ImageSource = fullfile(pathToMLAPP, 'battleship_right.png');
            app.dog_24 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_24.Visible = 'off';
            app.dog_24.ImageSource = fullfile(pathToMLAPP, 'dog_right.png');
            app.car_25 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_25.Visible = 'off';
            app.car_25.ImageSource = fullfile(pathToMLAPP, 'car_right.png');
            app.hat_25 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_25.Visible = 'off';
            app.hat_25.ImageSource = fullfile(pathToMLAPP, 'hat_right.png');
            app.battleship_25 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_25.Visible = 'off';
            app.battleship_25.ImageSource = fullfile(pathToMLAPP, 'battleship_right.png');
            app.dog_25 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_25.Visible = 'off';
            app.dog_25.ImageSource = fullfile(pathToMLAPP, 'dog_right.png');
            app.car_26 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_26.Visible = 'off';
            app.car_26.ImageSource = fullfile(pathToMLAPP, 'car_right.png');
            app.hat_26 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_26.Visible = 'off';
            app.hat_26.ImageSource = fullfile(pathToMLAPP, 'hat_right.png');
            app.battleship_26 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_26.Visible = 'off';
            app.battleship_26.ImageSource = fullfile(pathToMLAPP, 'battleship_right.png');
            app.dog_26 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_26.Visible = 'off';
            app.dog_26.ImageSource = fullfile(pathToMLAPP, 'dog_right.png');
            app.car_27 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_27.Visible = 'off';
            app.car_27.ImageSource = fullfile(pathToMLAPP, 'car_right.png');
            app.hat_27 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_27.Visible = 'off';
            app.hat_27.ImageSource = fullfile(pathToMLAPP, 'hat_right.png');
            app.battleship_27 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_27.Visible = 'off';
            app.battleship_27.ImageSource = fullfile(pathToMLAPP, 'battleship_right.png');
            app.dog_27 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_27.Visible = 'off';
            app.dog_27.ImageSource = fullfile(pathToMLAPP, 'dog_right.png');
            app.car_28 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_28.Visible = 'off';
            app.car_28.ImageSource = fullfile(pathToMLAPP, 'car_right.png');
            app.hat_28 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_28.Visible = 'off';
            app.hat_28.ImageSource = fullfile(pathToMLAPP, 'hat_right.png');
            app.battleship_28 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_28.Visible = 'off';
            app.battleship_28.ImageSource = fullfile(pathToMLAPP, 'battleship_right.png');
            app.dog_28 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_28.Visible = 'off';
            app.dog_28.ImageSource = fullfile(pathToMLAPP, 'dog_right.png');
            app.car_29 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_29.Visible = 'off';
            app.car_29.ImageSource = fullfile(pathToMLAPP, 'car_right.png');
            app.hat_29 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_29.Visible = 'off';
            app.hat_29.ImageSource = fullfile(pathToMLAPP, 'hat_right.png');
            app.battleship_29 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_29.Visible = 'off';
            app.battleship_29.ImageSource = fullfile(pathToMLAPP, 'battleship_right.png');
            app.dog_29 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_29.Visible = 'off';
            app.dog_29.ImageSource = fullfile(pathToMLAPP, 'dog_right.png');
            app.car_22 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_22.Visible = 'off';
            app.car_22.ImageSource = fullfile(pathToMLAPP, 'car_right.png');
            app.hat_22 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_22.Visible = 'off';
            app.hat_22.ImageSource = fullfile(pathToMLAPP, 'hat_right.png');
            app.battleship_22 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_22.Visible = 'off';
            app.battleship_22.ImageSource = fullfile(pathToMLAPP, 'battleship_right.png');
            app.dog_22 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_22.Visible = 'off';
            app.dog_22.ImageSource = fullfile(pathToMLAPP, 'dog_right.png');
            app.car_30 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_30.Visible = 'off';
            app.car_30.ImageSource = fullfile(pathToMLAPP, 'car_down.png');
            app.hat_30 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_30.Visible = 'off';
            app.hat_30.ImageSource = fullfile(pathToMLAPP, 'hat_down.png');
            app.battleship_30 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_30.Visible = 'off';
            app.battleship_30.ImageSource = fullfile(pathToMLAPP, 'battleship_down.png');
            app.dog_30 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_30.Visible = 'off';
            app.dog_30.ImageSource = fullfile(pathToMLAPP, 'dog_down.png');
            app.car_31 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_31.Visible = 'off';
            app.car_31.ImageSource = fullfile(pathToMLAPP, 'car_down.png');
            app.hat_31 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_31.Visible = 'off';
            app.hat_31.ImageSource = fullfile(pathToMLAPP, 'hat_down.png');
            app.battleship_31 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_31.Visible = 'off';
            app.battleship_31.ImageSource = fullfile(pathToMLAPP, 'battleship_down.png');
            app.dog_31 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_31.Visible = 'off';
            app.dog_31.ImageSource = fullfile(pathToMLAPP, 'dog_down.png');
            app.car_32 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_32.Visible = 'off';
            app.car_32.ImageSource = fullfile(pathToMLAPP, 'car_down.png');
            app.hat_32 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_32.Visible = 'off';
            app.hat_32.ImageSource = fullfile(pathToMLAPP, 'hat_down.png');
            app.battleship_32 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_32.Visible = 'off';
            app.battleship_32.ImageSource = fullfile(pathToMLAPP, 'battleship_down.png');
            app.dog_32 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_32.Visible = 'off';
            app.dog_32.ImageSource = fullfile(pathToMLAPP, 'dog_down.png');
            app.car_33 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_33.Visible = 'off';
            app.car_33.ImageSource = fullfile(pathToMLAPP, 'car_down.png');
            app.hat_33 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_33.Visible = 'off';
            app.hat_33.ImageSource = fullfile(pathToMLAPP, 'hat_down.png');
            app.battleship_33 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_33.Visible = 'off';
            app.battleship_33.ImageSource = fullfile(pathToMLAPP, 'battleship_down.png');
            app.dog_33 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_33.Visible = 'off';
            app.dog_33.ImageSource = fullfile(pathToMLAPP, 'dog_down.png');
            app.car_34 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_34.Visible = 'off';
            app.car_34.ImageSource = fullfile(pathToMLAPP, 'car_down.png');
            app.hat_34 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_34.Visible = 'off';
            app.hat_34.ImageSource = fullfile(pathToMLAPP, 'hat_down.png');
            app.battleship_34 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_34.Visible = 'off';
            app.battleship_34.ImageSource = fullfile(pathToMLAPP, 'battleship_down.png');
            app.dog_34 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_34.Visible = 'off';
            app.dog_34.ImageSource = fullfile(pathToMLAPP, 'dog_down.png');
            app.car_35 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_35.Visible = 'off';
            app.car_35.ImageSource = fullfile(pathToMLAPP, 'car_down.png');
            app.hat_35 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_35.Visible = 'off';
            app.hat_35.ImageSource = fullfile(pathToMLAPP, 'hat_down.png');
            app.battleship_35 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_35.Visible = 'off';
            app.battleship_35.ImageSource = fullfile(pathToMLAPP, 'battleship_down.png');
            app.dog_35 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_35.Visible = 'off';
            app.dog_35.ImageSource = fullfile(pathToMLAPP, 'dog_down.png');
            app.car_36 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_36.Visible = 'off';
            app.car_36.ImageSource = fullfile(pathToMLAPP, 'car_down.png');
            app.hat_36 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_36.Visible = 'off';
            app.hat_36.ImageSource = fullfile(pathToMLAPP, 'hat_down.png');
            app.battleship_36 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_36.Visible = 'off';
            app.battleship_36.ImageSource = fullfile(pathToMLAPP, 'battleship_down.png');
            app.dog_36 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_36.Visible = 'off';
            app.dog_36.ImageSource = fullfile(pathToMLAPP, 'dog_down.png');
            app.car_37 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_37.Visible = 'off';
            app.car_37.ImageSource = fullfile(pathToMLAPP, 'car_down.png');
            app.hat_37 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_37.Visible = 'off';
            app.hat_37.ImageSource = fullfile(pathToMLAPP, 'hat_down.png');
            app.battleship_37 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_37.Visible = 'off';
            app.battleship_37.ImageSource = fullfile(pathToMLAPP, 'battleship_down.png');
            app.dog_37 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_37.Visible = 'off';
            app.dog_37.ImageSource = fullfile(pathToMLAPP, 'dog_down.png');
            app.car_38 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_38.Visible = 'off';
            app.car_38.ImageSource = fullfile(pathToMLAPP, 'car_down.png');
            app.hat_38 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_38.Visible = 'off';
            app.hat_38.ImageSource = fullfile(pathToMLAPP, 'hat_down.png');
            app.battleship_38 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_38.Visible = 'off';
            app.battleship_38.ImageSource = fullfile(pathToMLAPP, 'battleship_down.png');
            app.dog_38 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_38.Visible = 'off';
            app.dog_38.ImageSource = fullfile(pathToMLAPP, 'dog_down.png');
            app.car_39 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_39.Visible = 'off';
            app.car_39.ImageSource = fullfile(pathToMLAPP, 'car_down.png');
            app.hat_39 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_39.Visible = 'off';
            app.hat_39.ImageSource = fullfile(pathToMLAPP, 'hat_down.png');
            app.battleship_39 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_39.Visible = 'off';
            app.battleship_39.ImageSource = fullfile(pathToMLAPP, 'battleship_down.png');
            app.dog_39 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_39.Visible = 'off';
            app.dog_39.ImageSource = fullfile(pathToMLAPP, 'dog_down.png');
            app.car_11 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_11.Visible = 'off';
            app.car_11.ImageSource = fullfile(pathToMLAPP, 'car_up.png');
            app.hat_11 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_11.Visible = 'off';
            app.hat_11.ImageSource = fullfile(pathToMLAPP, 'hat_up.png');
            app.battleship_11 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_11.Visible = 'off';
            app.battleship_11.ImageSource = fullfile(pathToMLAPP, 'battleship_up.png');
            app.dog_11 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_11.Visible = 'off';
            app.dog_11.ImageSource = fullfile(pathToMLAPP, 'dog_up.png');
            app.car_12 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_12.Visible = 'off';
            app.car_12.ImageSource = fullfile(pathToMLAPP, 'car_up.png');
            app.hat_12 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_12.Visible = 'off';
            app.hat_12.ImageSource = fullfile(pathToMLAPP, 'hat_up.png');
            app.battleship_12 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_12.Visible = 'off';
            app.battleship_12.ImageSource = fullfile(pathToMLAPP, 'battleship_up.png');
            app.dog_12 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_12.Visible = 'off';
            app.dog_12.ImageSource = fullfile(pathToMLAPP, 'dog_up.png');
            app.car_13 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_13.Visible = 'off';
            app.car_13.ImageSource = fullfile(pathToMLAPP, 'car_up.png');
            app.hat_13 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_13.Visible = 'off';
            app.hat_13.ImageSource = fullfile(pathToMLAPP, 'hat_up.png');
            app.battleship_13 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_13.Visible = 'off';
            app.battleship_13.ImageSource = fullfile(pathToMLAPP, 'battleship_up.png');
            app.dog_13 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_13.Visible = 'off';
            app.dog_13.ImageSource = fullfile(pathToMLAPP, 'dog_up.png');
            app.car_14 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_14.Visible = 'off';
            app.car_14.ImageSource = fullfile(pathToMLAPP, 'car_up.png');
            app.hat_14 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_14.Visible = 'off';
            app.hat_14.ImageSource = fullfile(pathToMLAPP, 'hat_up.png');
            app.battleship_14 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_14.Visible = 'off';
            app.battleship_14.ImageSource = fullfile(pathToMLAPP, 'battleship_up.png');
            app.dog_14 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_14.Visible = 'off';
            app.dog_14.ImageSource = fullfile(pathToMLAPP, 'dog_up.png');
            app.car_15 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_15.Visible = 'off';
            app.car_15.ImageSource = fullfile(pathToMLAPP, 'car_up.png');
            app.hat_15 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_15.Visible = 'off';
            app.hat_15.ImageSource = fullfile(pathToMLAPP, 'hat_up.png');
            app.battleship_15 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_15.Visible = 'off';
            app.battleship_15.ImageSource = fullfile(pathToMLAPP, 'battleship_up.png');
            app.dog_15 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_15.Visible = 'off';
            app.dog_15.ImageSource = fullfile(pathToMLAPP, 'dog_up.png');
            app.car_16 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_16.Visible = 'off';
            app.car_16.ImageSource = fullfile(pathToMLAPP, 'car_up.png');
            app.hat_16 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_16.Visible = 'off';
            app.hat_16.ImageSource = fullfile(pathToMLAPP, 'hat_up.png');
            app.battleship_16 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_16.Visible = 'off';
            app.battleship_16.ImageSource = fullfile(pathToMLAPP, 'battleship_up.png');
            app.dog_16 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_16.Visible = 'off';
            app.dog_16.ImageSource = fullfile(pathToMLAPP, 'dog_up.png');
            app.car_17 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_17.Visible = 'off';
            app.car_17.ImageSource = fullfile(pathToMLAPP, 'car_up.png');
            app.hat_17 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_17.Visible = 'off';
            app.hat_17.ImageSource = fullfile(pathToMLAPP, 'hat_up.png');
            app.battleship_17 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_17.Visible = 'off';
            app.battleship_17.ImageSource = fullfile(pathToMLAPP, 'battleship_up.png');
            app.dog_17 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_17.Visible = 'off';
            app.dog_17.ImageSource = fullfile(pathToMLAPP, 'dog_up.png');
            app.car_18 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_18.Visible = 'off';
            app.car_18.ImageSource = fullfile(pathToMLAPP, 'car_up.png');
            app.hat_18 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_18.Visible = 'off';
            app.hat_18.ImageSource = fullfile(pathToMLAPP, 'hat_up.png');
            app.battleship_18 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_18.Visible = 'off';
            app.battleship_18.ImageSource = fullfile(pathToMLAPP, 'battleship_up.png');
            app.dog_18 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_18.Visible = 'off';
            app.dog_18.ImageSource = fullfile(pathToMLAPP, 'dog_up.png');
            app.car_19 = uiimage(app.MonopolyInMatlabUIFigure);
            app.car_19.Visible = 'off';
            app.car_19.ImageSource = fullfile(pathToMLAPP, 'car_up.png');
            app.hat_19 = uiimage(app.MonopolyInMatlabUIFigure);
            app.hat_19.Visible = 'off';
            app.hat_19.ImageSource = fullfile(pathToMLAPP, 'hat_up.png');
            app.battleship_19 = uiimage(app.MonopolyInMatlabUIFigure);
            app.battleship_19.Visible = 'off';
            app.battleship_19.ImageSource = fullfile(pathToMLAPP, 'battleship_up.png');
            app.dog_19 = uiimage(app.MonopolyInMatlabUIFigure);
            app.dog_19.Visible = 'off';
            app.dog_19.ImageSource = fullfile(pathToMLAPP, 'dog_up.png');
            app.PaybailButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.PaybailButton.ButtonPushedFcn = createCallbackFcn(app, @PaybailButtonPushed, true);
            app.PaybailButton.Enable = 'off';
            app.PaybailButton.Text = 'Pay bail';
            app.ChooseAPropertyPanel = uipanel(app.MonopolyInMatlabUIFigure);
            app.ChooseAPropertyPanel.Title = 'Choose A Property';
            app.ChooseAPropertyPanel.Visible = 'off';
            app.ListBox = uilistbox(app.ChooseAPropertyPanel);
            app.card_drawn_panel = uipanel(app.MonopolyInMatlabUIFigure);
            app.card_drawn_panel.Visible = 'off';
            app.CardDrawnImage = uiimage(app.card_drawn_panel);
            app.CardDrawnImage.ImageSource = fullfile(pathToMLAPP, 'ChanceSplit', 'Chance1.png');
            app.OkButton = uibutton(app.card_drawn_panel, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.YouGotLabel = uilabel(app.card_drawn_panel);
            app.YouGotLabel.Text = 'You Got:';
            app.liquidation_panel = uipanel(app.MonopolyInMatlabUIFigure);
            app.liquidation_panel.Title = 'Choose items to sell/mortgage';
            app.liquidation_panel.Visible = 'off';
            app.SellMortgageTable = uitable(app.liquidation_panel);
            app.SellMortgageTable.ColumnName = '';
            app.SellMortgageTable.RowName = {};
            app.SellMortgageTable.SelectionType = 'row';
            app.SellMortgageTable.ColumnEditable = [true false false false];
            app.SellMortgageTable.CellEditCallback = createCallbackFcn(app, @SellMortgageTableCellEdit, true);
            app.Need0Label = uilabel(app.liquidation_panel);
            app.Need0Label.Text = 'Need: $0';
            app.Have0Label = uilabel(app.liquidation_panel);
            app.Have0Label.Text = 'Have: $0';
            app.SellMortgageSelectedButton = uibutton(app.liquidation_panel, 'push');
            app.SellMortgageSelectedButton.Text = 'Sell/Mortgage Selected';
            % Show the figure after all components are created
            app.MonopolyInMatlabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = game(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MonopolyInMatlabUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MonopolyInMatlabUIFigure)
        end
    end
end