classdef room_browser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MonopolyInMatlabUIFigure  matlab.ui.Figure
        Panel                     matlab.ui.container.Panel
        CloseButton               matlab.ui.control.Button
        JoinRoomButton            matlab.ui.control.Button
        EditField                 matlab.ui.control.EditField
        EnterUsernameLabel        matlab.ui.control.Label
        NALabel                   matlab.ui.control.Label
        BackButton                matlab.ui.control.Button
        RoomNameLabel             matlab.ui.control.Label
        XLabel                    matlab.ui.control.Label
        Image_2                   matlab.ui.control.Image
        NoneLabel                 matlab.ui.control.Label
        PlayersConnectedNALabel   matlab.ui.control.Label
        NoRoomSelectedButton      matlab.ui.control.Button
        Image                     matlab.ui.control.Image
        ListBox                   matlab.ui.control.ListBox
    end

    properties (Access = private)
        Rooms % game rooms found
        currentlySelectedRoomId
        dev_mode = false
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, dev_mode)
            import webRequest.*;

            app.dev_mode = dev_mode;

            if(app.dev_mode == true)
                response = webRequest("http://localhost:8000/menu/rooms");
            else
                response = webRequest("https://monopolyinmatlabserver-production.up.railway.app/menu/rooms");
            end
            if (~isempty(response.Body.Data))
                for i = 1 : length(response.Body.Data)
                    room = response.Body.Data(i);
                    app.Rooms{end+1} = room;
                    app.ListBox.Items{end+1} = room.roomName;
                end
            end
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            dev_mode_temp = app.dev_mode;
            delete(app);
            main_menu(dev_mode_temp);
        end

        % Button pushed function: NoRoomSelectedButton
        function NoRoomSelectedButtonPushed(app, event)
            app.Panel.Visible = "on"; % displaying the enter username panel
        end

        % Clicked callback: ListBox
        function ListBoxClicked(app, event)
            item = event.InteractionInformation.Item;
            if(isempty(item))
                return;
            end
            app.currentlySelectedRoomId = app.Rooms{item}.roomId;
            app.NALabel.Text = app.Rooms{item}.roomName;
            numPlayersConnected = length(app.Rooms{item}.gamePlayers);
            app.PlayersConnectedNALabel.Text = sprintf("Players Connected(%d):", numPlayersConnected);
            if(numPlayersConnected > 0)
                app.NoneLabel.Text = "";
                for player = app.Rooms{item}.gamePlayers
                    app.NoneLabel.Text = [app.NoneLabel.Text, {app.Rooms{item}.gamePlayers.playerName}];
                end
            end

            if(app.Rooms{item}.started)
                app.NoRoomSelectedButton.Text = "Game Already Started";
                app.NoRoomSelectedButton.Enable = "off";
            elseif(numPlayersConnected == 4)
                app.NoRoomSelectedButton.Text = "Room Full";
                app.NoRoomSelectedButton.Enable = "off";
            else
                app.NoRoomSelectedButton.Text = "Join Room";
                app.NoRoomSelectedButton.Enable = "on";
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Panel.Visible = "off";
            app.EditField.Value = "";
        end

        % Button pushed function: JoinRoomButton
        function JoinRoomButtonPushed(app, event)
            import webRequest.*;
            import StompClient.*;

            roomId = app.currentlySelectedRoomId;
            client = StompClient.getInstance();
            playerData = struct("playerName", app.EditField.Value); % this will be converted into json that the server can consume
            if(app.dev_mode == true)
                playerJoinResponse = webRequest(sprintf("http://localhost:8000/menu/rooms/%s/join", roomId), playerData); % this actually tells the server to put this player in the room
            else
                playerJoinResponse = webRequest(sprintf("https://monopolyinmatlabserver-production.up.railway.app/menu/rooms/%s/join", roomId), playerData); % this actually tells the server to put this player in the room
            end
            client.joinRoom(roomId, app.EditField.Value); % this subscribes to the room and sends a joined room broadcast to that room in the websocket server
            dev_mode_temp = app.dev_mode;
            delete(app);
            game(roomId, false, playerJoinResponse.Body.Data.playerId, dev_mode_temp);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MonopolyInMatlabUIFigure = uifigure('Visible', 'off');
            app.MonopolyInMatlabUIFigure.Name = 'Monopoly In Matlab';
            app.ListBox = uilistbox(app.MonopolyInMatlabUIFigure);
            app.ListBox.Items = {};
            app.ListBox.ClickedFcn = createCallbackFcn(app, @ListBoxClicked, true);
            app.ListBox.Value = {};
            app.Image = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image.ImageSource = 'Monopoly_game_logo.svg';
            app.NoRoomSelectedButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.NoRoomSelectedButton.ButtonPushedFcn = createCallbackFcn(app, @NoRoomSelectedButtonPushed, true);
            app.NoRoomSelectedButton.Enable = 'off';
            app.NoRoomSelectedButton.Text = 'No Room Selected';
            app.PlayersConnectedNALabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.PlayersConnectedNALabel.Text = 'Players Connected(N/A):';
            app.NoneLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.NoneLabel.Text = 'None';
            app.Image_2 = uiimage(app.MonopolyInMatlabUIFigure);
            app.Image_2.ImageSource = 'Matlab_Logo.png';
            app.XLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.XLabel.Text = 'X';
            app.RoomNameLabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.RoomNameLabel.Text = 'Room Name:';
            app.BackButton = uibutton(app.MonopolyInMatlabUIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.NALabel = uilabel(app.MonopolyInMatlabUIFigure);
            app.NALabel.Text = 'N/A';
            app.Panel = uipanel(app.MonopolyInMatlabUIFigure);
            app.Panel.Visible = 'off';
            app.EnterUsernameLabel = uilabel(app.Panel);
            app.EnterUsernameLabel.Text = 'Enter Username';
            app.EditField = uieditfield(app.Panel, 'text');
            app.JoinRoomButton = uibutton(app.Panel, 'push');
            app.JoinRoomButton.ButtonPushedFcn = createCallbackFcn(app, @JoinRoomButtonPushed, true);
            app.JoinRoomButton.Text = 'Join Room';
            app.CloseButton = uibutton(app.Panel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.MonopolyInMatlabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = room_browser(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MonopolyInMatlabUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MonopolyInMatlabUIFigure)
        end
    end
end